classdef PilotPlantApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PilotPlantInterfaceUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        BMT_Panel                      matlab.ui.container.Panel
        BMT_Controller_Panel           matlab.ui.container.Panel
        BMT_Controller_Switch          matlab.ui.control.Switch
        TypeDropDownLabel              matlab.ui.control.Label
        BMT_Controller_Type            matlab.ui.control.DropDown
        SPSpinnerLabel                 matlab.ui.control.Label
        SPSpinner                      matlab.ui.control.Spinner
        bmt_level                      matlab.ui.control.LinearGauge
        bmt_level_label                matlab.ui.control.Label
        All_Controllers_Lamp_9         matlab.ui.control.Lamp
        BMT_CUFT_Panel                 matlab.ui.container.Panel
        PumpSwitchLabel                matlab.ui.control.Label
        BMT_CUFT_Switch                matlab.ui.control.Switch
        SetpointSpinnerLabel           matlab.ui.control.Label
        BMT_CUFT_SP                    matlab.ui.control.Spinner
        All_Controllers_Panel          matlab.ui.container.Panel
        All_Controllers_Switch         matlab.ui.control.Switch
        All_Controllers_Lamp           matlab.ui.control.Lamp
        CUFT_Panel                     matlab.ui.container.Panel
        CUFT_Controller_Panel          matlab.ui.container.Panel
        CUFT_Controller_Switch         matlab.ui.control.Switch
        TypeDropDown_2Label            matlab.ui.control.Label
        CUFT_Controller_Type           matlab.ui.control.DropDown
        SPEditField_2Label             matlab.ui.control.Label
        CUFT_Controller_SP             matlab.ui.control.NumericEditField
        cuft_level                     matlab.ui.control.LinearGauge
        cuft_level_label               matlab.ui.control.Label
        All_Controllers_Lamp_8         matlab.ui.control.Lamp
        CUFT_ST_Panel                  matlab.ui.container.Panel
        PumpSwitchLabel_2              matlab.ui.control.Label
        CUFT_ST_Switch                 matlab.ui.control.Switch
        SetpointSpinnerLabel_2         matlab.ui.control.Label
        CUFT_ST_SP                     matlab.ui.control.Spinner
        NLT_NT_Panel                   matlab.ui.container.Panel
        PumpSwitchLabel_3              matlab.ui.control.Label
        nlt_nt_pump_onoff              matlab.ui.control.Switch
        SetpointSpinnerLabel_3         matlab.ui.control.Label
        nlt_nt_pump_speed              matlab.ui.control.Spinner
        NT_Panel                       matlab.ui.container.Panel
        NT_Controller_Panel            matlab.ui.container.Panel
        NT_Conroller_Switch            matlab.ui.control.Switch
        TypeDropDown_2Label_2          matlab.ui.control.Label
        NT_Controller_Type             matlab.ui.control.DropDown
        SPSpinner_5Label               matlab.ui.control.Label
        SPSpinner_5                    matlab.ui.control.Spinner
        NT_Level                       matlab.ui.control.LinearGauge
        Gauge_2Label_2                 matlab.ui.control.Label
        All_Controllers_Lamp_3         matlab.ui.control.Lamp
        NLT_Panel                      matlab.ui.container.Panel
        NLT_Controller_Panel           matlab.ui.container.Panel
        NLT_Controller_Switch          matlab.ui.control.Switch
        TypeDropDown_2Label_3          matlab.ui.control.Label
        NLT_Controller_Type            matlab.ui.control.DropDown
        SPSpinner_2Label               matlab.ui.control.Label
        SPSpinner_2                    matlab.ui.control.Spinner
        nlt_level                      matlab.ui.control.LinearGauge
        nlt_level_label                matlab.ui.control.Label
        All_Controllers_Lamp_2         matlab.ui.control.Lamp
        Raw_Water_Panel                matlab.ui.container.Panel
        SetpointSpinnerLabel_4         matlab.ui.control.Label
        FCV_541_SP                     matlab.ui.control.Spinner
        NT_CSTR1_Panel                 matlab.ui.container.Panel
        PumpSwitchLabel_4              matlab.ui.control.Label
        NT_CSTR1_Switch                matlab.ui.control.Switch
        SetpointSpinnerLabel_5         matlab.ui.control.Label
        NT_CSTR1_SP                    matlab.ui.control.Spinner
        CSTR3_Level_Panel              matlab.ui.container.Panel
        CSTR3_Level_ControllerPanel    matlab.ui.container.Panel
        CSTR3_Level_Controller_Switch  matlab.ui.control.Switch
        TypeDropDown_2Label_6          matlab.ui.control.Label
        CSTR3_Level_Controller_Type    matlab.ui.control.DropDown
        SPSpinner_3Label               matlab.ui.control.Label
        SPSpinner_3                    matlab.ui.control.Spinner
        CSTR3_Level                    matlab.ui.control.LinearGauge
        Gauge_2Label_6                 matlab.ui.control.Label
        All_Controllers_Lamp_7         matlab.ui.control.Lamp
        NTCSTR1_Temp_Panel             matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        NT_CSTR1_Temp                  matlab.ui.control.SemicircularGauge
        NT_CSTR1_Flow_Panel            matlab.ui.container.Panel
        Gauge_7Label                   matlab.ui.control.Label
        NT_CSTR1_Flow                  matlab.ui.control.SemicircularGauge
        CSTR1_Temp_Panel               matlab.ui.container.Panel
        CSTR1_Temp_Controller_Panel    matlab.ui.container.Panel
        CSTR1_Temp_Controller_Switch   matlab.ui.control.Switch
        TypeDropDown_2Label_7          matlab.ui.control.Label
        CSTR1_Temp_Controller_Type     matlab.ui.control.DropDown
        SPSpinner_6Label               matlab.ui.control.Label
        SPSpinner_6                    matlab.ui.control.Spinner
        CSTR1_Temp                     matlab.ui.control.LinearGauge
        Gauge_2Label_7                 matlab.ui.control.Label
        All_Controllers_Lamp_4         matlab.ui.control.Lamp
        CSTR2_Temp_Panel               matlab.ui.container.Panel
        CSTR2_Temp_Controller_Panel    matlab.ui.container.Panel
        CSTR2_Temp_Controller_Switch   matlab.ui.control.Switch
        TypeDropDown_2Label_8          matlab.ui.control.Label
        CSTR2_Temp_Controller_Type     matlab.ui.control.DropDown
        SPSpinner_7Label               matlab.ui.control.Label
        SPSpinner_7                    matlab.ui.control.Spinner
        CSTR2_Temp                     matlab.ui.control.LinearGauge
        Gauge_2Label_8                 matlab.ui.control.Label
        All_Controllers_Lamp_5         matlab.ui.control.Lamp
        CSTR3_Temp_Panel               matlab.ui.container.Panel
        CSTR3_Temp_Controller_Panel    matlab.ui.container.Panel
        CSTR3_Temp_Controller_Switc    matlab.ui.control.Switch
        TypeDropDown_2Label_9          matlab.ui.control.Label
        CSTR3_Temp_Controller_Type     matlab.ui.control.DropDown
        SPSpinner_4Label               matlab.ui.control.Label
        SPSpinner_4                    matlab.ui.control.Spinner
        CSTR3_Temp                     matlab.ui.control.LinearGauge
        Gauge_2Label_9                 matlab.ui.control.Label
        All_Controllers_Lamp_6         matlab.ui.control.Lamp
        STOP_Button                    matlab.ui.control.Button
        KillUIButton                   matlab.ui.control.Button
        LeavePlantRunningonExitCheckBox  matlab.ui.control.CheckBox
        Read_Only_Panel                matlab.ui.container.Panel
        Read_Only_Switch               matlab.ui.control.Switch
        Read_Only_Lamp                 matlab.ui.control.Lamp
        EnableMasterControlPanel       matlab.ui.container.Panel
        EnableMasterControlSwitch      matlab.ui.control.Switch
        MasterControlPanel             matlab.ui.container.Panel
        Master_Control_Switch          matlab.ui.control.Switch
        MasterControlSwitchLabel       matlab.ui.control.Label
        Master_Lamp                    matlab.ui.control.Lamp
        CSTR3_Out_Flow_Panel           matlab.ui.container.Panel
        Gauge_7Label_2                 matlab.ui.control.Label
        CSTR3_Out_Flow                 matlab.ui.control.SemicircularGauge
        SystemPanel_2                  matlab.ui.container.Panel
        TextLabel                      matlab.ui.control.Label
        DataUpdateCountLabel           matlab.ui.control.Label
        DataLampLabel                  matlab.ui.control.Label
        DataLamp                       matlab.ui.control.Lamp
        BackendLamp_3Label             matlab.ui.control.Label
        BackendLamp                    matlab.ui.control.Lamp
        TextLabel_2                    matlab.ui.control.Label
        LastHeartbeatLabel             matlab.ui.control.Label
        ForceCheckButton               matlab.ui.control.Button
        HeartbeatLabel                 matlab.ui.control.Label
        HeartbeatLamp                  matlab.ui.control.Lamp
        Panel                          matlab.ui.container.Panel
        StepUpButton                   matlab.ui.control.Button
        StepDownButton                 matlab.ui.control.Button
    end

    %% PilotPlantApp     User interface app


    %% Created by
    %   Ewan, Andy, Aydan / S1, 2021 / ENG445 / Murdoch University
    
    properties (Access = private)
        UiHandler % Parent control, the PilotPlant.UserInterface class
        ControlIdMap containers.Map %
        ControlArray
        ControlLabelMap containers.Map % Map of tag -> ControlLabelArray index of corresponding control labels.
        ControlLabelArray
        
        UpdateUiControllerEventListener event.listener;
        UpdateUiControlValueEventListener event.listener
        UpdateInterfaceMasterControlEventListener event.listener
        DataUpdatedEventListener event.listener
        
        UpdateInterfaceControllerOnEventListener event.listener;
        UpdateInterfaceControllerOffEventListener event.listener;
        
        BackendHeartbeatEventListener event.listener;
        
        DataUpdateCount uint64 = 0;
        BackendHeartbeatTic uint64 = 0;
        BackendHeartbeatToc uint64 = 0;
        HeartbeatCount uint64 = 0;
        
        
        MasterControlStatus logical = false


    end
    
    properties (Access = public)
        SystemCheckTimer timer;
    end
    
    properties (Access = private, Constant = true)
        ButtonOnColour = '0,1,0';
        ButtonOffColour = '0.80,0.80,0.80';
    end
    
    events
        InterfaceMasterControlToggleEvent
        
        InterfaceStartedEvent
        
        InterfaceSwitchToggledEvent
        InterfaceValueUpdatedEvent
        
        InterfaceStopButtonPushedEvent
        
        InterfaceControllerTurnedOnEvent
        InterfaceControllerTurnedOffEvent
        InterfaceControllerSetpointChangedEvent
        InterfaceControllersAllOnEvent
        InterfaceControllersAllOffEvent
        
        InterfaceControllerStatusUpdatedEvent
        InterfaceAllControllersToggledEvent
        
        InterfaceTerminatingEvent
    end
    
    methods (Access = private)
        %% systemCheck
        % Runs periodically on a timer to check system status
        function app = systemCheck(app, ~, ~)
            % Check system is running correctly
            PilotPlant.Debug.Print("SystemCheck", 5);
            
            if app.BackendHeartbeatTic < 1
                app.BackendLamp.Color = app.ButtonOffColour;
                return;
            end
            heartbeatToc = toc(app.BackendHeartbeatTic);
            
            if heartbeatToc < 5
                app.BackendLamp.Color = app.ButtonOnColour;
            else
                app.BackendLamp.Color = app.ButtonOffColour;
            end
        end
        
        function notifyUiTerminating(app)
            PilotPlant.Debug.Print("Firing UiTerminating event...");
            leaveRunning = false;
            if app.LeavePlantRunningonExitCheckBox.Value
                leaveRunning = true;
            end
            app = app.KillTimers();
            notify(app, 'InterfaceTerminatingEvent', PilotPlant.EventData.UiTerminating(leaveRunning));
        end
        
        %% Send notification of toggle control changing
        function notifyToggleControl(app, event, ControlId)
            arguments
                app;
                event event.EventData;
                ControlId string;
            end
            
            event.addprop('ControlId');
            event.ControlId = ControlId;
            
            notify(app, 'InterfaceSwitchToggledEvent', event);
        end
        
        %% setUpObjects
        
        function [success, app] = setUpObjects(app)
            success = false;
            
            PilotPlant.Debug.Print("Setting up control objects...");
            
            controlObjects = [ ...
                app.Master_Control_Switch...
                app.Master_Lamp...
                app.All_Controllers_Switch...
                app.All_Controllers_Lamp...
                app.bmt_level...
                app.NT_Level...
                app.nlt_level...
                app.cuft_level...
                app.CSTR1_Temp...
                app.CSTR2_Temp...
                app.CSTR3_Temp...
                app.CSTR3_Level...
                app.BMT_CUFT_Switch...
                app.BMT_CUFT_SP...
                app.nlt_nt_pump_onoff...
                app.nlt_nt_pump_speed...
                app.CUFT_ST_Switch...
                app.CUFT_ST_SP...
                app.NT_CSTR1_Flow...
                app.NT_CSTR1_Switch...
                app.NT_CSTR1_SP ...
                app.NT_CSTR1_Temp ...
                app.CSTR3_Out_Flow
                ];
            
            
            controlIds = [ ...
                PilotPlant.UserInterface.MasterControlId ...
                strcat(PilotPlant.UserInterface.MasterControlId,".lamp") ...
                strcat(PilotPlant.UserInterface.AllControllersControlId,".switch")...
                strcat(PilotPlant.UserInterface.AllControllersControlId,".lamp") ...
                "bmt.level"...
                "nt.level"...
                "nlt.level"...
                "cuft.level"...
                "cstr1.temp" ...
                "cstr2.temp" ...
                "cstr3.temp" ...
                "cstr3.level"...
                "bmt.cuft.pump.onoff"...
                "bmt.cuft.pump.speed" ...
                "nlt.nt.pump.onoff"...
                "nlt.nt.pump.speed" ...
                "cuft.st.pump.onoff"...
                "cuft.st.pump.speed" ...
                "nt.cstr1.flow" ...
                "nt.cstr1.pump.onoff" ...
                "nt.cstr1.pump.speed" ...
                "nt.cstr1.temp" ...
                "cstr3.out.flow"
                ];
            
            controlLabelParents = [];
            controlLabelArray = [];
            
            for i = 1 : length(controlIds)
                % controlObjects(i).Tag = controlIds(i);
                control = controlObjects(i);
                isError = false;
                
                if ~isprop(control,'Tag') || isempty(control.Tag)
                    isError = true;
                    message = sprintf("Control index %d has no tag! Expecting '%s'.", i, controlIds(i));
                elseif ~strcmp(controlIds(i),controlObjects(i).Tag)
                    isError = true;
                    message = sprintf("Control index %d tag does not match! Expected tag: '%s', Actual tag: '%s'", i, controlIds(i), controlObjects(i).Tag);
                end
                
                if isError
                    PilotPlant.Debug.Print(message);
                    get(control)
                    throw(MException('PilotPlantApp:SetupFailure',message));
                end
                
                % Figure out which ones have labels
                %                 if control.Tag == "cuft.level"
                %                     controlLabel = control.Parent.findobj({'Type','uilabel','Parent',control.Parent});
                %                     for j = 1 : length(controlLabel)
                %                         controlLabel(j).Tag
                %                         get(controlLabel(j).Parent)
                %                     end
                %                 end
                %
                controlLabel = control.Parent.findobj({'Tag', strcat(control.Tag, ".label"), 'Parent', control.Parent});
                if ~isempty(controlLabel)
                    controlLabel.Text = "--";
                    controlLabelArray = [controlLabelArray; controlLabel];
                    controlLabelParents = [controlLabelParents; string(control.Tag)];
                end
            end
            
            % Reassign list to all controller type dropdowns
            % controllerList = {'None', 'PI', 'GMC', 'DMC'};
            controllerList = {'PI', 'GMC', 'DMC'};
            gmcControllers = ["cstr3.level","cstr3.temp","nlt.level"];
            controllerTypeLists = app.GridLayout.findobj({'Type', 'uidropdown'});
            for i = 1 : length(controllerTypeLists)
                controllerType = controllerTypeLists(i,1);
                if ~isprop(controllerType, 'Tag') || ~contains(controllerType.Tag, ".controller.type")
                    continue;
                end
                
                controlId = strrep(controllerType.Tag, ".controller.type", "");
                
                % Set specific controllers to GMC
                if find(gmcControllers == controlId)
                    controllerType.Items = {'PI','GMC','DMC'};
                    % controllerType.Value = 'GMC';
                    controllerType.Value = 'GMC';
                    
                else
                    % Otherwise default to PI
                    controllerType.Items = {'PI'};
                    controllerType.Value = 'PI';
                end
                
            end
            
            % Build control label map for easy access later
            if ~isempty(controlLabelParents)
                app.ControlLabelMap = containers.Map(controlLabelParents, 1:length(controlLabelParents));
                app.ControlLabelArray = controlLabelArray;
            end
            
            % Build control map for easy access later
            app.ControlIdMap = containers.Map(controlIds, 1:length(controlIds));
            app.ControlArray = controlObjects;
            
            PilotPlant.Debug.Print("Control objects setup.");
            success = true;
        end
        
        
        %% Update control value by tag key
        function app = updateControlValue(app, controlTag, data)
            controlKey = app.ControlIdMap(controlTag);
            control = app.ControlArray(controlKey);
            
            
            value = data(controlTag);
            %                         control = app.ControlArray(controlKey); %.Value = data(key).Value;
            
            switch control.Type
                case 'uiswitch'
                    if value == 1
                        controlValue = 'On';
                    else
                        controlValue = 'Off';
                    end
                otherwise
                    controlValue = cast(value, class(control.Value));
            end
            
            if ~isempty(controlValue)
                control.Value = controlValue;
                % If a label control exists, update it.
                if app.ControlLabelMap.isKey(control.Tag)
                    labelControlId = app.ControlLabelMap(control.Tag);
                    if isnumeric(controlValue)
                        % controlValue = round(controlValue,4,"significant");
                        controlValue = sprintf("%.2f", controlValue);
                    end
                    app.ControlLabelArray(labelControlId).Text = string(controlValue);
                end
            end
        end
        
        function app = setButtonColour(app, control, value)
            arguments
                app PilotPlantApp;
                control;
                value string;
            end
            if value == "On"
                control.Color = app.ButtonOnColour;
            else
                control.Color = app.ButtonOffColour;
            end
        end
        
        function app = setLampDisplay(app, control, lampOn)
            arguments
                app PilotPlantApp;
                control;
                lampOn logical;
            end
            
            PilotPlant.Debug.Print("Setting lamp");
            if lampOn
                control.Color = app.ButtonOnColour;
            else
                control.Color = app.ButtonOffColour;
            end
        end
    end
    
    methods (Access = public)
        
        
        function app = KillTimers(app)
            PilotPlant.Debug.Print("Killing timers...");
            if ~isempty(app.SystemCheckTimer)
                try
                    stop(app.SystemCheckTimer);
                catch exception
                    % do nothing
                end
                delete(app.SystemCheckTimer);
            end
        end
        
        function app = SetMasterControl(app, masterControlStatus)
            app.MasterControlStatus = masterControlStatus;
            % todo: would like setLampDisplay() to handle this more
            % generically, access issues using events preventing
            if masterControlStatus
                app.Master_Control_Switch.Value = 'On';
                app.Master_Lamp.Color = app.ButtonOnColour;
            else
                app.Master_Control_Switch.Value = 'Off';
                app.Master_Lamp.Color = app.ButtonOffColour;
                % todo: reset all data being displayed so it doesn't appear
                % live anymore (as it isn't)
            end
            % app.Master_Control_Switch.Enable = true;
        end
        
        %% UpdateInterfaceControllerOnEventHandler
        function app = UpdateInterfaceControllerOnEventHandler(app, ~, event)
            PilotPlant.Debug.Print("UpdateInterfaceControllerOnEvent notification received.");
            controlTag = strcat(event.ControlId, ".controller.onoff");
            controls = app.GridLayout.findobj({'Tag', controlTag, 'Type', 'uiswitch'});
            if isempty(controls)
                PilotPlant.Debug.Print("No corresponding control found.");
                disp(event)
            end
            controls.Value = 'On';
            if isprop(event, 'StartingSp') && isnumeric(event.StartingSp) && event.StartingSp >= 0
                controlTag = strcat(event.ControlId, ".controller.sp");
                controls = app.GridLayout.findobj({'Tag', controlTag, 'Type', 'uispinner'});
                if ~isempty(controls)
                    controls.Value = double(event.StartingSp);
                end
            end
            
            controlTag = strcat(event.ControlId, ".controller.lamp");
            controls = app.GridLayout.findobj({'Tag', controlTag, 'Type', 'uilamp'});
            if ~isempty(controls)
                controls.Color = app.ButtonOnColour;
            end
        end
        
        %% UpdateInterfaceControllerOffEventHandler
        function app = UpdateInterfaceControllerOffEventHandler(app, ~, event)
            PilotPlant.Debug.Print("UpdateInterfaceControllerOffEvent notification received.");
            controlTag = strcat(event.ControlId, ".controller.onoff");
            controls = app.GridLayout.findobj({'Tag', controlTag, 'Type', 'uiswitch'});
            if isempty(controls)
                PilotPlant.Debug.Print("No corresponding control found.");
                disp(event)
            end
            controls.Value = 'Off';
            
            controlTag = strcat(event.ControlId, ".controller.lamp");
            controls = app.GridLayout.findobj({'Tag', controlTag, 'Type', 'uilamp'});
            if ~isempty(controls)
                controls.Color = app.ButtonOffColour;
            end
            
        end
        
        %% BackendHeartbeatEventHandler
        function app = BackendHeartbeatEventHandler(app, ~, ~)
            PilotPlant.Debug.Print("BackendHeartbeat notification received.", 5);
            if app.BackendHeartbeatTic < 1
                app.HeartbeatLamp.Color = app.ButtonOffColour;
                app.BackendHeartbeatTic = tic();
                return;
            end
            
            app.HeartbeatCount = app.HeartbeatCount + 1;
            if mod(app.HeartbeatCount, 2) == 0
                app.HeartbeatLamp.Color = app.ButtonOffColour;
            else
                app.HeartbeatLamp.Color = app.ButtonOnColour;
            end
            app.BackendHeartbeatToc = toc(app.BackendHeartbeatTic);
            app.LastHeartbeatLabel.Text = string(app.BackendHeartbeatToc);
            app.BackendHeartbeatTic = tic();
        end
        
        %% DataUpdateEventHandler
        function app = DataUpdatedEventHandler(app, ~, eventData)
            PilotPlant.Debug.Print("Data update notification received.", 5);
            if ~strcmp(eventData.EventName,'DataUpdatedEvent')
                PilotPlant.Debug.Warning("Unknown event type received:");
                disp(eventData);
                return;
            end
            
            
            app.DataUpdateCount = app.DataUpdateCount + 1;
            
            if mod(app.DataUpdateCount,2) == 0
                app.DataLamp.Color = app.ButtonOnColour;
            else
                app.DataLamp.Color = app.ButtonOffColour;
            end
            
            app.DataUpdateCountLabel.Text = string(app.DataUpdateCount);
            
            data = eventData.Data;
            
            for key = keys(data)
                % app.ControlIdMap = containers.Map(controlIds, 1:length(controlIds));
                % app.ControlArray = controlObjects;
                stringKey = string(key);
                if app.ControlIdMap.isKey(stringKey)
                    app = app.updateControlValue(stringKey, data);
                else
                    control = app.GridLayout.findobj({'Tag', stringKey});
                    if ~isempty(control)
                        control.Value = data(stringKey);
                    end
                end
            end
        end
        
        %% Event handler for master control update calls from outside
        function app = UpdateInterfaceMasterControlEventHandler(app, ~, Event)
            PilotPlant.Debug.Print("UpdateInterfaceMasterControlEvent notification received.");
            if ~isprop(Event,'EventName') || ~strcmp(Event.EventName,'UpdateInterfaceMasterControlEvent')
                PilotPlant.Debug.Print("Unexpected event data.");
                return;
            end
            app = app.SetMasterControl(Event.Enabled);
        end
        
        %% Event Handler for updating control values
        function app = UpdateUiControlValueEventHandler(app, ~, Event)
            if ~isprop(Event,'ControlId')
                return;
            end
            controlId = Event.ControlId;
            PilotPlant.Debug.Print(sprintf("Updating control value for ID '%s'.", controlId));
            
            switch controlId
                case PilotPlant.UserInterface.MasterControlId
                    if isprop(Event, 'IsToggle') && isprop(Event, 'ToggleState')
                        app = app.SetMasterControl(Event.ToggleState);
                    end
                    
                    % if Event.Value
                    % app.Master_Lamp.Color
                    % else
                    % app = app.setLampDisplay("x", Event.Value);
                otherwise
                    PilotPlant.Debug.Print(sprintf("Unattached control: %s", controlId));
                    % Do nothing
            end
        end
        
        %% Event Handler for updating values
        function success = UpdateValue(app, ItemTag, ItemValue)
            arguments
                app PilotPlantApp;
                ItemTag string;
                ItemValue;
            end
            
            success = false;
            if ~app.ControlIdMap.isKey(ItemTag)
                return;
            end
            
            object = app.ControlIdMap(itemTag);
            if ~isobject(object) || ~isprop(object, 'Value')
                return;
            end
            try
                object.Value = ItemValue;
                success = true;
            catch exception
            end
        end
        
        %% Setup interface
        function [success, app] = SetupInterface(app)
            PilotPlant.Debug.Print("Setting up Interface...");
            [success, app] = app.setUpObjects();
            if ~success
                PilotPlant.Debug.Print("Object setup failed.");
                return;
            end
            PilotPlant.Debug.Print("Interface setup.");
            success = true;
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UiStart(app, UiHandler)
            
            app.UiHandler = UiHandler;
            
            app.UpdateInterfaceMasterControlEventListener = addlistener(UiHandler, 'UpdateInterfaceMasterControlEvent', @app.UpdateInterfaceMasterControlEventHandler);
            app.UpdateUiControlValueEventListener = addlistener(UiHandler, 'UpdateInterfaceControlValueEvent',@app.UpdateUiControlValueEventHandler);
            app.DataUpdatedEventListener = addlistener(UiHandler,'DataUpdatedEvent',@app.DataUpdatedEventHandler);
            app.BackendHeartbeatEventListener = addlistener(UiHandler, 'HeartbeatEvent', @app.BackendHeartbeatEventHandler);
            % app.UpdateUiControllerEventListener = addlistener(UiHandler, 'UpdateInterfaceControllerEvent', @app.UpdateUiControllerEventHandler);
            app.UpdateInterfaceControllerOnEventListener = addlistener(UiHandler, 'UpdateInterfaceControllerOnEvent', @app.UpdateInterfaceControllerOnEventHandler);
            app.UpdateInterfaceControllerOffEventListener = addlistener(UiHandler, 'UpdateInterfaceControllerOffEvent', @app.UpdateInterfaceControllerOffEventHandler);
            
            PilotPlant.Debug.Print("***** Interface instantiated.");
            notify(app, "InterfaceStartedEvent");
            
            app.SystemCheckTimer = timer('Name','SystemCheckTimer',"TimerFcn", @app.systemCheck, 'Period', 1, 'ExecutionMode', "fixedRate");
            start(app.SystemCheckTimer);
            
        end

        % Value changed function: Master_Control_Switch
        function Master_Control_SwitchValueChanged(app, event)
            % Deliberately decoupled from notifyToggleControl
            event.addprop('ControlId');
            event.ControlId = PilotPlant.UserInterface.MasterControlId;
            notify(app, 'InterfaceSwitchToggledEvent', event);
            if strcmp(app.Master_Control_Switch.Value,'On')
                app.EnableMasterControlSwitch.Enable = false;
            else
                app.EnableMasterControlSwitch.Enable = true;
            end
        end

        % Value changed function: All_Controllers_Switch
        function All_Controllers_SwitchValueChanged(app, event)
            
            if ~strcmp(event.Source.Value,'On')
                PilotPlant.Debug.Print("Turning off all controllers");
                notify(app, 'InterfaceControllersAllOffEvent');
                return;
            end
            
            PilotPlant.Debug.Print("Turning on all controllers");
            
            % Need to transport all controller data over if ON.
            allControllerNames = [
                "bmt.level.controller";
                "cuft.level.controller";
                "nlt.level.controller";
                "nt.level.controller";
                "cstr1.temp.controller";
                "cstr2.temp.controller";
                "cstr3.level.controller";
                "cstr3.temp.controller";
                ];
            
            controllerTypes = [];
            controllersPacking = [];
            for i = 1 : length(allControllerNames)
                controller = allControllerNames(i);
                controllerType = app.GridLayout.findobj({'Tag',strcat(controller,".type")});
                if isempty(controllerType)
                    PilotPlant.Debug.Print(sprintf("Controller not found for %s", controller));
                    continue;
                end
                controllerType = controllerType(1);
                controllerTypes = [controllerTypes; string(controllerType.Value)];
                controllersPacking = [controllersPacking; strrep(controller, ".controller", "")];
            end
            
            controllerTypes = containers.Map(controllersPacking, controllerTypes);
            eventData = PilotPlant.EventData.Controllers.AllOn(controllerTypes);
%             disp(eventData)
            
            notify(app, 'InterfaceControllersAllOnEvent', eventData);
        end

        % Close request function: PilotPlantInterfaceUIFigure
        function UiClose(app, event)
            app = app.KillTimers();
            PilotPlant.Debug.ClassCleaning();
            PilotPlant.Debug.Print("(this will not notify of cleaned)");
            app.notifyUiTerminating();
        end

        % Button pushed function: KillUIButton
        function KillUIButtonPushed(app, event)
            app = app.KillTimers();
            app.notifyUiTerminating();
            delete(app);
        end

        % Value changed function: BMT_CUFT_SP, CUFT_ST_SP, 
        % FCV_541_SP, NT_CSTR1_SP, nlt_nt_pump_speed
        function UiValueChanged(app, event)
            if ~isprop(event, 'Source') || ~isprop(event.Source, 'Tag')
                return;
            end
            event.addprop('ControlId');
            event.ControlId = event.Source.Tag;
            notify(app, 'InterfaceValueUpdatedEvent', event);
        end

        % Value changed function: BMT_Controller_Switch, 
        % CSTR1_Temp_Controller_Switch, 
        % CSTR2_Temp_Controller_Switch, 
        % CSTR3_Level_Controller_Switch, 
        % CSTR3_Temp_Controller_Switc, CUFT_Controller_Switch, 
        % NLT_Controller_Switch, NT_Conroller_Switch
        function ControllerToggleChanged(app, event)
            if ~isprop(event,'Value') || ~isprop(event, 'Source') || ~isprop(event.Source, 'Tag')
                PilotPlant.Debug.Print("!! Invalid event received.");
                return;
            end
            
            controllerId = strrep(event.Source.Tag,".onoff","");
            
            PilotPlant.Debug.Print(sprintf("Controller toggled: %s", controllerId));
            
            % Find type, SP controlers
            controllerTypeDropdown = event.Source.Parent.findobj({'Tag',strcat(controllerId,".type"),'Type','uidropdown'});
            controllerSpSpinner = event.Source.Parent.findobj({'Tag',strcat(controllerId,".sp"),'Type','uispinner'});
            
            if isempty(controllerTypeDropdown) || isempty(controllerSpSpinner)
                PilotPlant.Debug.Print("Unknown controller state changed.");
                disp(event);
                return;
            end
            
            controllerTypeDropdown = controllerTypeDropdown(1);
            controllerSpSpinner = controllerSpSpinner(1);
            
            % If turning off, notify front and bail out
            if strcmp(event.Value,'Off')
                controllerTypeDropdown.Enable = true;
                notify(app, 'InterfaceControllerTurnedOffEvent', PilotPlant.EventData.Controllers.TurnedOff(controllerId));
                return;
            end
            
            controllerType = controllerTypeDropdown.Value;
            
            % If type is none, can't activate
            if strcmp('None',controllerType)
                event.Source.Value = event.PreviousValue;
                uialert(app.PilotPlantInterfaceUIFigure, "Controller cannot be activated until a type is selected.", "Error","Icon","error");
                return;
            end
            
            % Disable controller type selection while active
            controllerTypeDropdown.Enable = false;
            
            % Setpoint
            controllerSp = int32(controllerSpSpinner.Value);
            if controllerSp <= 0
                controllerSp = -1;
            end
            
            % Notify the back
            notify(app, 'InterfaceControllerTurnedOnEvent', PilotPlant.EventData.Controllers.TurnedOn(controllerId, controllerSp, controllerType));
        end

        % Callback function
        function ControlValueChanged(app, event)
            % value = app.BMT_Controller_SP.Value;
            
            if ~isprop(event, 'Source') || ~isprop(event.Source, 'Tag')
                return;
            end
            event.addprop('ControlId');
            event.ControlId = event.Source.Tag;
            
            notify(app, 'InterfaceValueUpdatedEvent', event);
        end

        % Value changed function: BMT_Controller_Type, 
        % CSTR1_Temp_Controller_Type, CSTR2_Temp_Controller_Type, 
        % CSTR3_Level_Controller_Type, CSTR3_Temp_Controller_Type, 
        % CUFT_Controller_Type, NLT_Controller_Type, 
        % NT_Controller_Type
        function ControllerTypeChanged(app, event)
            % We don't want to allow on-the-fly changing at the moment.
            % To change controller type, stop it, change, start it.
            %             if ~isprop(event, 'Source') || ~isprop(event.Source, 'Tag')
            %                 return;
            %             end
            %             event.addprop('ControlId');
            %             event.ControlId = event.Source.Tag;
            %
            %             notify(app, 'UiControllerTypeUpdated', event);
            
        end

        % Value changed function: CUFT_Controller_SP, SPSpinner, 
        % SPSpinner_2, SPSpinner_3, SPSpinner_4, SPSpinner_5, 
        % SPSpinner_6, SPSpinner_7
        function ControllerSpChanged(app, event)
            if ~isprop(event,'Value') || ~isprop(event, 'Source') || ~isprop(event.Source, 'Tag')
                PilotPlant.Debug.Print("Invalid event received.");
                disp(event)
                return;
            end
            
            controllerId = strrep(event.Source.Tag,".sp","");
            
            % Setpoint
            controllerSp = event.Value;
            
            % Notify the back
            notify(app, 'InterfaceControllerSetpointChangedEvent', PilotPlant.EventData.Controllers.SetpointUpdated(controllerId, controllerSp));
            
            
        end

        % Value changed function: BMT_CUFT_Switch, CUFT_ST_Switch, 
        % NT_CSTR1_Switch, nlt_nt_pump_onoff
        function UiToggleChanged(app, event)
            if ~isprop(event, 'Source') || ~isprop(event.Source, 'Tag')
                return;
            end
            app.notifyToggleControl(event, event.Source.Tag);
        end

        % Value changed function: EnableMasterControlSwitch
        function Enable_Master_Control_EnableMasterControlSwitchChanged(app, event)
            value = app.EnableMasterControlSwitch.Value;
            app.Master_Control_Switch.Enable = value;
        end

        % Value changed function: Read_Only_Switch
        function Read_Only_Mode_SwitchValueChanged(app, event)
            app.notifyToggleControl(event, PilotPlant.UserInterface.ReadOnlyModeControlId);
            if strcmp(event.Value, 'On')
                app.Read_Only_Lamp.Color = app.ButtonOnColour;
            else
                app.Read_Only_Lamp.Color = app.ButtonOffColour;
            end
        end

        % Button pushed function: STOP_Button
        function STOPButtonPushed(app, event)
            notify(app, 'InterfaceStopButtonPushedEvent');
        end

        % Button pushed function: ForceCheckButton
        function ButtonSystemForceCheck(app, event)
            app.systemCheck();
        end

        % Button pushed function: StepUpButton
        function StepUpButtonPressed(app, event)
            controls = app.GridLayout.findobj({'Type','uispinner'});
            for i = 1 : length(controls)

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PilotPlantInterfaceUIFigure = uifigure('Visible', 'off');
            app.PilotPlantInterfaceUIFigure.Name = 'Pilot Plant Interface';
            app.PilotPlantInterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @UiClose, true);
            app.GridLayout = uigridlayout(app.PilotPlantInterfaceUIFigure);
            app.BMT_Panel = uipanel(app.GridLayout);
            app.BMT_Panel.Enable = 'off';
            app.BMT_Panel.Title = 'BMT';
            app.BMT_Controller_Panel = uipanel(app.BMT_Panel);
            app.BMT_Controller_Panel.Title = 'Controller';
            app.BMT_Controller_Switch = uiswitch(app.BMT_Controller_Panel, 'slider');
            app.BMT_Controller_Switch.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.BMT_Controller_Switch.Tag = 'bmt.controller.onoff';
            app.TypeDropDownLabel = uilabel(app.BMT_Controller_Panel);
            app.TypeDropDownLabel.Text = 'Type';
            app.BMT_Controller_Type = uidropdown(app.BMT_Controller_Panel);
            app.BMT_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.BMT_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.BMT_Controller_Type.Tag = 'bmt.controller.type';
            app.BMT_Controller_Type.Value = 'None';
            app.SPSpinnerLabel = uilabel(app.BMT_Controller_Panel);
            app.SPSpinnerLabel.Text = 'SP';
            app.SPSpinner = uispinner(app.BMT_Controller_Panel);
            app.SPSpinner.Limits = [0 100];
            app.SPSpinner.ValueDisplayFormat = '%.0f';
            app.SPSpinner.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.SPSpinner.Tag = 'bmt.controller.sp';
            app.bmt_level = uigauge(app.BMT_Panel, 'linear');
            app.bmt_level.Tag = 'bmt.level';
            app.bmt_level_label = uilabel(app.BMT_Panel);
            app.bmt_level_label.Tag = 'bmt.level.label';
            app.bmt_level_label.Text = '100';
            app.All_Controllers_Lamp_9 = uilamp(app.BMT_Panel);
            app.All_Controllers_Lamp_9.Tag = 'bmt.controller.lamp';
            app.BMT_CUFT_Panel = uipanel(app.GridLayout);
            app.BMT_CUFT_Panel.Title = 'BMT -> CUFT';
            app.PumpSwitchLabel = uilabel(app.BMT_CUFT_Panel);
            app.PumpSwitchLabel.Text = 'Pump';
            app.BMT_CUFT_Switch = uiswitch(app.BMT_CUFT_Panel, 'slider');
            app.BMT_CUFT_Switch.ValueChangedFcn = createCallbackFcn(app, @UiToggleChanged, true);
            app.BMT_CUFT_Switch.Tag = 'bmt.cuft.pump.onoff';
            app.SetpointSpinnerLabel = uilabel(app.BMT_CUFT_Panel);
            app.SetpointSpinnerLabel.Text = 'Setpoint';
            app.BMT_CUFT_SP = uispinner(app.BMT_CUFT_Panel);
            app.BMT_CUFT_SP.ValueChangedFcn = createCallbackFcn(app, @UiValueChanged, true);
            app.BMT_CUFT_SP.Tag = 'bmt.cuft.pump.speed';
            app.All_Controllers_Panel = uipanel(app.GridLayout);
            app.All_Controllers_Panel.Title = 'All Controllers';
            app.All_Controllers_Switch = uiswitch(app.All_Controllers_Panel, 'slider');
            app.All_Controllers_Switch.ValueChangedFcn = createCallbackFcn(app, @All_Controllers_SwitchValueChanged, true);
            app.All_Controllers_Switch.Tag = 'all_controllers.switch';
            app.All_Controllers_Lamp = uilamp(app.All_Controllers_Panel);
            app.All_Controllers_Lamp.Tag = 'all_controllers.lamp';
            app.CUFT_Panel = uipanel(app.GridLayout);
            app.CUFT_Panel.Enable = 'off';
            app.CUFT_Panel.Title = 'CUFT';
            app.CUFT_Controller_Panel = uipanel(app.CUFT_Panel);
            app.CUFT_Controller_Panel.Title = 'Controller';
            app.CUFT_Controller_Switch = uiswitch(app.CUFT_Controller_Panel, 'slider');
            app.CUFT_Controller_Switch.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.CUFT_Controller_Switch.Tag = 'cuft.controller.onoff';
            app.TypeDropDown_2Label = uilabel(app.CUFT_Controller_Panel);
            app.TypeDropDown_2Label.Text = 'Type';
            app.CUFT_Controller_Type = uidropdown(app.CUFT_Controller_Panel);
            app.CUFT_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.CUFT_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.CUFT_Controller_Type.Tag = 'cuft.controller.type';
            app.CUFT_Controller_Type.Value = 'None';
            app.SPEditField_2Label = uilabel(app.CUFT_Controller_Panel);
            app.SPEditField_2Label.Text = 'SP';
            app.CUFT_Controller_SP = uieditfield(app.CUFT_Controller_Panel, 'numeric');
            app.CUFT_Controller_SP.Limits = [0 100];
            app.CUFT_Controller_SP.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.CUFT_Controller_SP.Tag = 'cuft.controller.sp';
            app.cuft_level = uigauge(app.CUFT_Panel, 'linear');
            app.cuft_level.Tag = 'cuft.level';
            app.cuft_level_label = uilabel(app.CUFT_Panel);
            app.cuft_level_label.Tag = 'cuft.level.label';
            app.cuft_level_label.Text = '100';
            app.All_Controllers_Lamp_8 = uilamp(app.CUFT_Panel);
            app.All_Controllers_Lamp_8.Tag = 'cuft.controller.lamp';
            app.CUFT_ST_Panel = uipanel(app.GridLayout);
            app.CUFT_ST_Panel.Title = 'CUFT -> ST';
            app.PumpSwitchLabel_2 = uilabel(app.CUFT_ST_Panel);
            app.PumpSwitchLabel_2.Text = 'Pump';
            app.CUFT_ST_Switch = uiswitch(app.CUFT_ST_Panel, 'slider');
            app.CUFT_ST_Switch.ValueChangedFcn = createCallbackFcn(app, @UiToggleChanged, true);
            app.CUFT_ST_Switch.Tag = 'cuft.st.pump.onoff';
            app.SetpointSpinnerLabel_2 = uilabel(app.CUFT_ST_Panel);
            app.SetpointSpinnerLabel_2.Text = 'Setpoint';
            app.CUFT_ST_SP = uispinner(app.CUFT_ST_Panel);
            app.CUFT_ST_SP.ValueChangedFcn = createCallbackFcn(app, @UiValueChanged, true);
            app.CUFT_ST_SP.Tag = 'cuft.st.pump.speed';
            app.NLT_NT_Panel = uipanel(app.GridLayout);
            app.NLT_NT_Panel.Title = 'NLT -> NT';
            app.PumpSwitchLabel_3 = uilabel(app.NLT_NT_Panel);
            app.PumpSwitchLabel_3.Text = 'Pump';
            app.nlt_nt_pump_onoff = uiswitch(app.NLT_NT_Panel, 'slider');
            app.nlt_nt_pump_onoff.ValueChangedFcn = createCallbackFcn(app, @UiToggleChanged, true);
            app.nlt_nt_pump_onoff.Tag = 'nlt.nt.pump.onoff';
            app.SetpointSpinnerLabel_3 = uilabel(app.NLT_NT_Panel);
            app.SetpointSpinnerLabel_3.Text = 'Setpoint';
            app.nlt_nt_pump_speed = uispinner(app.NLT_NT_Panel);
            app.nlt_nt_pump_speed.ValueChangedFcn = createCallbackFcn(app, @UiValueChanged, true);
            app.nlt_nt_pump_speed.Tag = 'nlt.nt.pump.speed';
            app.NT_Panel = uipanel(app.GridLayout);
            app.NT_Panel.Title = 'NT Level';
            app.NT_Controller_Panel = uipanel(app.NT_Panel);
            app.NT_Controller_Panel.Title = 'Controller';
            app.NT_Conroller_Switch = uiswitch(app.NT_Controller_Panel, 'slider');
            app.NT_Conroller_Switch.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.NT_Conroller_Switch.Tag = 'nt.level.controller.onoff';
            app.TypeDropDown_2Label_2 = uilabel(app.NT_Controller_Panel);
            app.TypeDropDown_2Label_2.Text = 'Type';
            app.NT_Controller_Type = uidropdown(app.NT_Controller_Panel);
            app.NT_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.NT_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.NT_Controller_Type.Tag = 'nt.level.controller.type';
            app.NT_Controller_Type.Value = 'None';
            app.SPSpinner_5Label = uilabel(app.NT_Controller_Panel);
            app.SPSpinner_5Label.Text = 'SP';
            app.SPSpinner_5 = uispinner(app.NT_Controller_Panel);
            app.SPSpinner_5.Limits = [0 100];
            app.SPSpinner_5.ValueDisplayFormat = '%.0f';
            app.SPSpinner_5.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.SPSpinner_5.Tag = 'nt.level.controller.sp';
            app.NT_Level = uigauge(app.NT_Panel, 'linear');
            app.NT_Level.Tag = 'nt.level';
            app.Gauge_2Label_2 = uilabel(app.NT_Panel);
            app.Gauge_2Label_2.Tag = 'nt.level.label';
            app.Gauge_2Label_2.Text = '100';
            app.All_Controllers_Lamp_3 = uilamp(app.NT_Panel);
            app.All_Controllers_Lamp_3.Tag = 'nt.level.controller.lamp';
            app.NLT_Panel = uipanel(app.GridLayout);
            app.NLT_Panel.Title = 'NLT Level';
            app.NLT_Controller_Panel = uipanel(app.NLT_Panel);
            app.NLT_Controller_Panel.Title = 'Controller';
            app.NLT_Controller_Switch = uiswitch(app.NLT_Controller_Panel, 'slider');
            app.NLT_Controller_Switch.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.NLT_Controller_Switch.Tag = 'nlt.level.controller.onoff';
            app.TypeDropDown_2Label_3 = uilabel(app.NLT_Controller_Panel);
            app.TypeDropDown_2Label_3.Text = 'Type';
            app.NLT_Controller_Type = uidropdown(app.NLT_Controller_Panel);
            app.NLT_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.NLT_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.NLT_Controller_Type.Tag = 'nlt.level.controller.type';
            app.NLT_Controller_Type.Value = 'None';
            app.SPSpinner_2Label = uilabel(app.NLT_Controller_Panel);
            app.SPSpinner_2Label.Text = 'SP';
            app.SPSpinner_2 = uispinner(app.NLT_Controller_Panel);
            app.SPSpinner_2.Limits = [0 100];
            app.SPSpinner_2.ValueDisplayFormat = '%.0f';
            app.SPSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.SPSpinner_2.Tag = 'nlt.level.controller.sp';
            app.nlt_level = uigauge(app.NLT_Panel, 'linear');
            app.nlt_level.Tag = 'nlt.level';
            app.nlt_level_label = uilabel(app.NLT_Panel);
            app.nlt_level_label.Tag = 'nlt.level.label';
            app.nlt_level_label.Text = '100';
            app.All_Controllers_Lamp_2 = uilamp(app.NLT_Panel);
            app.All_Controllers_Lamp_2.Tag = 'nlt.level.controller.lamp';
            app.Raw_Water_Panel = uipanel(app.GridLayout);
            app.Raw_Water_Panel.Title = 'Raw Water';
            app.SetpointSpinnerLabel_4 = uilabel(app.Raw_Water_Panel);
            app.SetpointSpinnerLabel_4.Text = 'Setpoint';
            app.FCV_541_SP = uispinner(app.Raw_Water_Panel);
            app.FCV_541_SP.ValueChangedFcn = createCallbackFcn(app, @UiValueChanged, true);
            app.FCV_541_SP.Tag = 'raw.nlt.valve';
            app.NT_CSTR1_Panel = uipanel(app.GridLayout);
            app.NT_CSTR1_Panel.Title = 'NT -> CSTR1';
            app.PumpSwitchLabel_4 = uilabel(app.NT_CSTR1_Panel);
            app.PumpSwitchLabel_4.Text = 'Pump';
            app.NT_CSTR1_Switch = uiswitch(app.NT_CSTR1_Panel, 'slider');
            app.NT_CSTR1_Switch.ValueChangedFcn = createCallbackFcn(app, @UiToggleChanged, true);
            app.NT_CSTR1_Switch.Tag = 'nt.cstr1.pump.onoff';
            app.SetpointSpinnerLabel_5 = uilabel(app.NT_CSTR1_Panel);
            app.SetpointSpinnerLabel_5.Text = 'Setpoint';
            app.NT_CSTR1_SP = uispinner(app.NT_CSTR1_Panel);
            app.NT_CSTR1_SP.ValueChangedFcn = createCallbackFcn(app, @UiValueChanged, true);
            app.NT_CSTR1_SP.Tag = 'nt.cstr1.pump.speed';
            app.CSTR3_Level_Panel = uipanel(app.GridLayout);
            app.CSTR3_Level_Panel.Title = 'CSTR3 Level';
            app.CSTR3_Level_ControllerPanel = uipanel(app.CSTR3_Level_Panel);
            app.CSTR3_Level_ControllerPanel.Title = 'Controller';
            app.CSTR3_Level_Controller_Switch = uiswitch(app.CSTR3_Level_ControllerPanel, 'slider');
            app.CSTR3_Level_Controller_Switch.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.CSTR3_Level_Controller_Switch.Tag = 'cstr3.level.controller.onoff';
            app.TypeDropDown_2Label_6 = uilabel(app.CSTR3_Level_ControllerPanel);
            app.TypeDropDown_2Label_6.Text = 'Type';
            app.CSTR3_Level_Controller_Type = uidropdown(app.CSTR3_Level_ControllerPanel);
            app.CSTR3_Level_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.CSTR3_Level_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.CSTR3_Level_Controller_Type.Tag = 'cstr3.level.controller.type';
            app.CSTR3_Level_Controller_Type.Value = 'None';
            app.SPSpinner_3Label = uilabel(app.CSTR3_Level_ControllerPanel);
            app.SPSpinner_3Label.Text = 'SP';
            app.SPSpinner_3 = uispinner(app.CSTR3_Level_ControllerPanel);
            app.SPSpinner_3.Limits = [0 100];
            app.SPSpinner_3.ValueDisplayFormat = '%.0f';
            app.SPSpinner_3.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.SPSpinner_3.Tag = 'cstr3.level.controller.sp';
            app.CSTR3_Level = uigauge(app.CSTR3_Level_Panel, 'linear');
            app.CSTR3_Level.Tag = 'cstr3.level';
            app.Gauge_2Label_6 = uilabel(app.CSTR3_Level_Panel);
            app.Gauge_2Label_6.Tag = 'cstr3.level.label';
            app.Gauge_2Label_6.Text = '100';
            app.All_Controllers_Lamp_7 = uilamp(app.CSTR3_Level_Panel);
            app.All_Controllers_Lamp_7.Tag = 'cstr3.level.controller.lamp';
            app.NTCSTR1_Temp_Panel = uipanel(app.GridLayout);
            app.NTCSTR1_Temp_Panel.Title = 'NT -> CSTR1 Temp';
            app.Label = uilabel(app.NTCSTR1_Temp_Panel);
            app.Label.Tag = 'nt.cstr1.temp.label';
            app.Label.Text = '45';
            app.NT_CSTR1_Temp = uigauge(app.NTCSTR1_Temp_Panel, 'semicircular');
            app.NT_CSTR1_Temp.Tag = 'nt.cstr1.temp';
            app.NT_CSTR1_Flow_Panel = uipanel(app.GridLayout);
            app.NT_CSTR1_Flow_Panel.Title = 'NT -> CSTR1 Flow';
            app.Gauge_7Label = uilabel(app.NT_CSTR1_Flow_Panel);
            app.Gauge_7Label.Tag = 'nt.cstr1.flow.label';
            app.Gauge_7Label.Text = '45';
            app.NT_CSTR1_Flow = uigauge(app.NT_CSTR1_Flow_Panel, 'semicircular');
            app.NT_CSTR1_Flow.Tag = 'nt.cstr1.flow';
            app.CSTR1_Temp_Panel = uipanel(app.GridLayout);
            app.CSTR1_Temp_Panel.Title = 'CSTR1 Temp';
            app.CSTR1_Temp_Controller_Panel = uipanel(app.CSTR1_Temp_Panel);
            app.CSTR1_Temp_Controller_Panel.Title = 'Controller';
            app.CSTR1_Temp_Controller_Switch = uiswitch(app.CSTR1_Temp_Controller_Panel, 'slider');
            app.CSTR1_Temp_Controller_Switch.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.CSTR1_Temp_Controller_Switch.Tag = 'cstr1.temp.controller.onoff';
            app.TypeDropDown_2Label_7 = uilabel(app.CSTR1_Temp_Controller_Panel);
            app.TypeDropDown_2Label_7.Text = 'Type';
            app.CSTR1_Temp_Controller_Type = uidropdown(app.CSTR1_Temp_Controller_Panel);
            app.CSTR1_Temp_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.CSTR1_Temp_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.CSTR1_Temp_Controller_Type.Tag = 'cstr1.temp.controller.type';
            app.CSTR1_Temp_Controller_Type.Value = 'None';
            app.SPSpinner_6Label = uilabel(app.CSTR1_Temp_Controller_Panel);
            app.SPSpinner_6Label.Text = 'SP';
            app.SPSpinner_6 = uispinner(app.CSTR1_Temp_Controller_Panel);
            app.SPSpinner_6.Limits = [0 100];
            app.SPSpinner_6.ValueDisplayFormat = '%.0f';
            app.SPSpinner_6.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.SPSpinner_6.Tag = 'cstr1.temp.controller.sp';
            app.CSTR1_Temp = uigauge(app.CSTR1_Temp_Panel, 'linear');
            app.CSTR1_Temp.Tag = 'cstr1.temp';
            app.Gauge_2Label_7 = uilabel(app.CSTR1_Temp_Panel);
            app.Gauge_2Label_7.Tag = 'cstr1.temp.label';
            app.Gauge_2Label_7.Text = '100';
            app.All_Controllers_Lamp_4 = uilamp(app.CSTR1_Temp_Panel);
            app.All_Controllers_Lamp_4.Tag = 'cstr1.temp.controller.lamp';
            app.CSTR2_Temp_Panel = uipanel(app.GridLayout);
            app.CSTR2_Temp_Panel.Title = 'CSTR2 Temp';
            app.CSTR2_Temp_Controller_Panel = uipanel(app.CSTR2_Temp_Panel);
            app.CSTR2_Temp_Controller_Panel.Title = 'Controller';
            app.CSTR2_Temp_Controller_Switch = uiswitch(app.CSTR2_Temp_Controller_Panel, 'slider');
            app.CSTR2_Temp_Controller_Switch.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.CSTR2_Temp_Controller_Switch.Tag = 'cstr2.temp.controller.onoff';
            app.TypeDropDown_2Label_8 = uilabel(app.CSTR2_Temp_Controller_Panel);
            app.TypeDropDown_2Label_8.Text = 'Type';
            app.CSTR2_Temp_Controller_Type = uidropdown(app.CSTR2_Temp_Controller_Panel);
            app.CSTR2_Temp_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.CSTR2_Temp_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.CSTR2_Temp_Controller_Type.Tag = 'cstr2.temp.controller.type';
            app.CSTR2_Temp_Controller_Type.Value = 'None';
            app.SPSpinner_7Label = uilabel(app.CSTR2_Temp_Controller_Panel);
            app.SPSpinner_7Label.Text = 'SP';
            app.SPSpinner_7 = uispinner(app.CSTR2_Temp_Controller_Panel);
            app.SPSpinner_7.Limits = [0 100];
            app.SPSpinner_7.ValueDisplayFormat = '%.0f';
            app.SPSpinner_7.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.SPSpinner_7.Tag = 'cstr2.temp.controller.sp';
            app.CSTR2_Temp = uigauge(app.CSTR2_Temp_Panel, 'linear');
            app.CSTR2_Temp.Tag = 'cstr2.temp';
            app.Gauge_2Label_8 = uilabel(app.CSTR2_Temp_Panel);
            app.Gauge_2Label_8.Tag = 'cstr2.temp.label';
            app.Gauge_2Label_8.Text = '100';
            app.All_Controllers_Lamp_5 = uilamp(app.CSTR2_Temp_Panel);
            app.All_Controllers_Lamp_5.Tag = 'cstr2.temp.controller.lamp';
            app.CSTR3_Temp_Panel = uipanel(app.GridLayout);
            app.CSTR3_Temp_Panel.Title = 'CSTR3 Temp';
            app.CSTR3_Temp_Controller_Panel = uipanel(app.CSTR3_Temp_Panel);
            app.CSTR3_Temp_Controller_Panel.Title = 'Controller';
            app.CSTR3_Temp_Controller_Switc = uiswitch(app.CSTR3_Temp_Controller_Panel, 'slider');
            app.CSTR3_Temp_Controller_Switc.ValueChangedFcn = createCallbackFcn(app, @ControllerToggleChanged, true);
            app.CSTR3_Temp_Controller_Switc.Tag = 'cstr3.temp.controller.onoff';
            app.TypeDropDown_2Label_9 = uilabel(app.CSTR3_Temp_Controller_Panel);
            app.TypeDropDown_2Label_9.Text = 'Type';
            app.CSTR3_Temp_Controller_Type = uidropdown(app.CSTR3_Temp_Controller_Panel);
            app.CSTR3_Temp_Controller_Type.Items = {'None', 'PI', 'PID', 'GMC'};
            app.CSTR3_Temp_Controller_Type.ValueChangedFcn = createCallbackFcn(app, @ControllerTypeChanged, true);
            app.CSTR3_Temp_Controller_Type.Tag = 'cstr3.temp.controller.type';
            app.CSTR3_Temp_Controller_Type.Value = 'None';
            app.SPSpinner_4Label = uilabel(app.CSTR3_Temp_Controller_Panel);
            app.SPSpinner_4Label.Text = 'SP';
            app.SPSpinner_4 = uispinner(app.CSTR3_Temp_Controller_Panel);
            app.SPSpinner_4.Limits = [0 100];
            app.SPSpinner_4.ValueDisplayFormat = '%.0f';
            app.SPSpinner_4.ValueChangedFcn = createCallbackFcn(app, @ControllerSpChanged, true);
            app.SPSpinner_4.Tag = 'cstr3.temp.controller.sp';
            app.CSTR3_Temp = uigauge(app.CSTR3_Temp_Panel, 'linear');
            app.CSTR3_Temp.Tag = 'cstr3.temp';
            app.Gauge_2Label_9 = uilabel(app.CSTR3_Temp_Panel);
            app.Gauge_2Label_9.Tag = 'cstr3.temp.label';
            app.Gauge_2Label_9.Text = '100';
            app.All_Controllers_Lamp_6 = uilamp(app.CSTR3_Temp_Panel);
            app.All_Controllers_Lamp_6.Tag = 'cstr3.temp.controller.lamp';
            app.STOP_Button = uibutton(app.GridLayout, 'push');
            app.STOP_Button.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOP_Button.Text = 'STOP';
            app.KillUIButton = uibutton(app.GridLayout, 'push');
            app.KillUIButton.ButtonPushedFcn = createCallbackFcn(app, @KillUIButtonPushed, true);
            app.KillUIButton.Text = 'Kill UI';
            app.LeavePlantRunningonExitCheckBox = uicheckbox(app.GridLayout);
            app.LeavePlantRunningonExitCheckBox.Tooltip = {'Disable turning the plant Master Control off on exit. This only occurs if this program has specifically turned it on.'};
            app.LeavePlantRunningonExitCheckBox.Text = 'Leave Plant Running on Exit';
            app.LeavePlantRunningonExitCheckBox.Value = true;
            app.Read_Only_Panel = uipanel(app.GridLayout);
            app.Read_Only_Panel.Tooltip = {'Enable reading data from the plant without Master Control.'};
            app.Read_Only_Panel.Title = 'Read-Only Mode';
            app.Read_Only_Switch = uiswitch(app.Read_Only_Panel, 'slider');
            app.Read_Only_Switch.ValueChangedFcn = createCallbackFcn(app, @Read_Only_Mode_SwitchValueChanged, true);
            app.Read_Only_Switch.Tag = 'read_only.switch';
            app.Read_Only_Lamp = uilamp(app.Read_Only_Panel);
            app.Read_Only_Lamp.Tag = 'all_controllers.lamp';
            app.EnableMasterControlPanel = uipanel(app.GridLayout);
            app.EnableMasterControlPanel.Title = 'Enable Master Control';
            app.EnableMasterControlSwitch = uiswitch(app.EnableMasterControlPanel, 'slider');
            app.EnableMasterControlSwitch.ValueChangedFcn = createCallbackFcn(app, @Enable_Master_Control_EnableMasterControlSwitchChanged, true);
            app.EnableMasterControlSwitch.Tooltip = {'UI will lock out Master Control until this is enabled.'};
            app.MasterControlPanel = uipanel(app.GridLayout);
            app.MasterControlPanel.Title = 'Master Control';
            app.Master_Control_Switch = uiswitch(app.MasterControlPanel, 'slider');
            app.Master_Control_Switch.ValueChangedFcn = createCallbackFcn(app, @Master_Control_SwitchValueChanged, true);
            app.Master_Control_Switch.Tag = 'master';
            app.Master_Control_Switch.Enable = 'off';
            app.MasterControlSwitchLabel = uilabel(app.MasterControlPanel);
            app.MasterControlSwitchLabel.Text = 'Master Control';
            app.Master_Lamp = uilamp(app.MasterControlPanel);
            app.Master_Lamp.Tag = 'master.lamp';
            app.CSTR3_Out_Flow_Panel = uipanel(app.GridLayout);
            app.CSTR3_Out_Flow_Panel.Title = 'CSTR3 -> Out flow';
            app.Gauge_7Label_2 = uilabel(app.CSTR3_Out_Flow_Panel);
            app.Gauge_7Label_2.Tag = 'cstr3.out.flow.label';
            app.Gauge_7Label_2.Text = '45';
            app.CSTR3_Out_Flow = uigauge(app.CSTR3_Out_Flow_Panel, 'semicircular');
            app.CSTR3_Out_Flow.Tag = 'cstr3.out.flow';
            app.SystemPanel_2 = uipanel(app.GridLayout);
            app.SystemPanel_2.Title = 'System';
            app.TextLabel = uilabel(app.SystemPanel_2);
            app.TextLabel.Text = 'Data Updates';
            app.DataUpdateCountLabel = uilabel(app.SystemPanel_2);
            app.DataUpdateCountLabel.Text = '0';
            app.DataLampLabel = uilabel(app.SystemPanel_2);
            app.DataLampLabel.Text = 'Data';
            app.DataLamp = uilamp(app.SystemPanel_2);
            app.BackendLamp_3Label = uilabel(app.SystemPanel_2);
            app.BackendLamp_3Label.Text = 'Backend';
            app.BackendLamp = uilamp(app.SystemPanel_2);
            app.TextLabel_2 = uilabel(app.SystemPanel_2);
            app.TextLabel_2.Text = 'Last Heartbeat';
            app.LastHeartbeatLabel = uilabel(app.SystemPanel_2);
            app.LastHeartbeatLabel.Text = '0';
            app.ForceCheckButton = uibutton(app.SystemPanel_2, 'push');
            app.ForceCheckButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonSystemForceCheck, true);
            app.ForceCheckButton.Text = 'Force Check';
            app.HeartbeatLabel = uilabel(app.SystemPanel_2);
            app.HeartbeatLabel.Text = 'Heartbeat';
            app.HeartbeatLamp = uilamp(app.SystemPanel_2);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.StepUpButton = uibutton(app.Panel, 'push');
            app.StepUpButton.ButtonPushedFcn = createCallbackFcn(app, @StepUpButtonPressed, true);
            app.StepUpButton.Text = 'Step Up';
            app.StepDownButton = uibutton(app.Panel, 'push');
            app.StepDownButton.Text = 'Step Down';
            % Show the figure after all components are created
            app.PilotPlantInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PilotPlantApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PilotPlantInterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)UiStart(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PilotPlantInterfaceUIFigure)
        end
    end
end